classdef mini_gland_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiniGlandUIFigure               matlab.ui.Figure
        AcinusSimulationLabel           matlab.ui.control.Label
        DuctSimulationLabel             matlab.ui.control.Label
        RunSimulationButtonDuct         matlab.ui.control.Button
        CheckElectroneutralityCheckBox  matlab.ui.control.CheckBox
        SaveVisDataCheckBox             matlab.ui.control.CheckBox
        ParotidMiniGlandSimulationLabel  matlab.ui.control.Label
        RunSimulationButtonAcini        matlab.ui.control.Button
        DisplayPlotsCheckBox_2          matlab.ui.control.CheckBox
        RealCellsLabel                  matlab.ui.control.Label
        NHE_alpha_AEditFieldLabel       matlab.ui.control.Label
        NHE_alpha_AEditField            matlab.ui.control.NumericEditField
        NHE_alpha_BEditFieldLabel       matlab.ui.control.Label
        NHE_alpha_BEditField            matlab.ui.control.NumericEditField
        VPLCEditFieldLabel              matlab.ui.control.Label
        VPLCEditField                   matlab.ui.control.NumericEditField
        parameterEditFieldLabel         matlab.ui.control.Label
        parameterEditField              matlab.ui.control.NumericEditField
        parameterEditField_2Label       matlab.ui.control.Label
        parameterEditField_2            matlab.ui.control.NumericEditField
        parameterEditField_3Label       matlab.ui.control.Label
        parameterEditField_3            matlab.ui.control.NumericEditField
        parameterEditField_4Label       matlab.ui.control.Label
        parameterEditField_4            matlab.ui.control.NumericEditField
        parameterEditField_5Label       matlab.ui.control.Label
        parameterEditField_5            matlab.ui.control.NumericEditField
        parameterEditField_6Label       matlab.ui.control.Label
        parameterEditField_6            matlab.ui.control.NumericEditField
        parameterEditField_7Label       matlab.ui.control.Label
        parameterEditField_7            matlab.ui.control.NumericEditField
        parameterEditField_8Label       matlab.ui.control.Label
        parameterEditField_8            matlab.ui.control.NumericEditField
        parameterEditField_9Label       matlab.ui.control.Label
        parameterEditField_9            matlab.ui.control.NumericEditField
        parameterEditField_10Label      matlab.ui.control.Label
        parameterEditField_10           matlab.ui.control.NumericEditField
        parameterEditField_11Label      matlab.ui.control.Label
        parameterEditField_11           matlab.ui.control.NumericEditField
        parameterEditField_12Label      matlab.ui.control.Label
        parameterEditField_12           matlab.ui.control.NumericEditField
        parameterEditField_13Label      matlab.ui.control.Label
        parameterEditField_13           matlab.ui.control.NumericEditField
        parameterEditField_14Label      matlab.ui.control.Label
        parameterEditField_14           matlab.ui.control.NumericEditField
        parameterEditField_15Label      matlab.ui.control.Label
        parameterEditField_15           matlab.ui.control.NumericEditField
        parameterEditField_16Label      matlab.ui.control.Label
        parameterEditField_16           matlab.ui.control.NumericEditField
        parameterEditField_17Label      matlab.ui.control.Label
        parameterEditField_17           matlab.ui.control.NumericEditField
        parameterEditField_18Label      matlab.ui.control.Label
        parameterEditField_18           matlab.ui.control.NumericEditField
        parameterEditField_19Label      matlab.ui.control.Label
        parameterEditField_19           matlab.ui.control.NumericEditField
        SyntheticCellsLabel             matlab.ui.control.Label
        DuctSimplifyModelCheckBox       matlab.ui.control.CheckBox
        C1Button_2                      matlab.ui.control.StateButton
        C2Button                        matlab.ui.control.StateButton
        C3Button                        matlab.ui.control.StateButton
        C4Button                        matlab.ui.control.StateButton
        C5Button                        matlab.ui.control.StateButton
        C6Button                        matlab.ui.control.StateButton
        C7Button                        matlab.ui.control.StateButton
        C8Button                        matlab.ui.control.StateButton
        C9Button                        matlab.ui.control.StateButton
        C10Button                       matlab.ui.control.StateButton
        C11Button                       matlab.ui.control.StateButton
        C12Button                       matlab.ui.control.StateButton
        C13Button                       matlab.ui.control.StateButton
        C14Button                       matlab.ui.control.StateButton
        C1Button_3                      matlab.ui.control.StateButton
        C2Button_2                      matlab.ui.control.StateButton
        C3Button_2                      matlab.ui.control.StateButton
        C4Button_2                      matlab.ui.control.StateButton
        C5Button_2                      matlab.ui.control.StateButton
        C6Button_2                      matlab.ui.control.StateButton
        C7Button_2                      matlab.ui.control.StateButton
        ExVivoButton                    matlab.ui.control.Button
        InVivoButton                    matlab.ui.control.Button
        SetDefaultLabel                 matlab.ui.control.Label
        DuctDisplayPlotsCheckBox        matlab.ui.control.CheckBox
        InputDropDownLabel              matlab.ui.control.Label
        DuctInputDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        Parms = 'parms_ex-vivo.ini' % default simulation parameters file name
        deployed_app_path = '~/Desktop/MiniGland/' % Description
     end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Launching MiniGland app...');
            files = {dir("acinus/result_*.mat").name}; % acinus result files
            if length(files) > 0 
                app.DuctInputDropDown.Items = files;
                app.DuctInputDropDown.Value = files(1);
            end
             

        end

        % Button pushed function: RunSimulationButtonAcini
        function RunSimulationButtonAciniPushed(app, event)
            disp('Running acinus simulation...');
            run_acinus();
            disp('Acinus simulation complete');
        end

        % Button pushed function: RunSimulationButtonDuct
        function RunSimulationButtonDuctPushed(app, event)
            if strcmp(get(app.DuctInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Running duct simulation...');
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running duct simulation','Indeterminate','on');
            run_duct(app.Parms, ...
                get(app.DuctSimplifyModelCheckBox,'Value'), ...
                get(app.DuctDisplayPlotsCheckBox,'Value'), ...
                get(app.DuctInputDropDown,'Value'));
            close(d)
            disp('Duct simulation complete.');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Duct simulation complete','Icon','success');
        end

        % Button pushed function: ExVivoButton
        function ExVivoButtonPushed(app, event)
            disp('Loading ex-vivo parms.');
            app.Parms = 'parms_ex-vivo.ini';
        end

        % Button pushed function: InVivoButton
        function InVivoButtonPushed(app, event)
            disp('Loading in-vivo parms.');
            app.Parms = 'parms_in-vivo.ini';
        end

        % Close request function: MiniGlandUIFigure
        function MiniGlandUIFigureCloseRequest(app, event)
            delete(app)
            disp('Exiting MiniGland app');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiniGlandUIFigure = uifigure('Visible', 'off');
            app.MiniGlandUIFigure.Name = 'MiniGland';
            app.MiniGlandUIFigure.CloseRequestFcn = createCallbackFcn(app, @MiniGlandUIFigureCloseRequest, true);
            app.AcinusSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.AcinusSimulationLabel.Text = 'Acinus Simulation';
            app.DuctSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.DuctSimulationLabel.Text = 'Duct Simulation';
            app.RunSimulationButtonDuct = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonDuct.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonDuctPushed, true);
            app.RunSimulationButtonDuct.Text = 'Run Simulation';
            app.CheckElectroneutralityCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.CheckElectroneutralityCheckBox.Text = 'Check Electroneutrality';
            app.SaveVisDataCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.SaveVisDataCheckBox.Text = 'Save Vis Data';
            app.ParotidMiniGlandSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.ParotidMiniGlandSimulationLabel.Text = 'Parotid Mini-Gland Simulation';
            app.RunSimulationButtonAcini = uibutton(app.MiniGlandUIFigure, 'push');
            app.RunSimulationButtonAcini.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonAciniPushed, true);
            app.RunSimulationButtonAcini.Text = 'Run Simulation';
            app.DisplayPlotsCheckBox_2 = uicheckbox(app.MiniGlandUIFigure);
            app.DisplayPlotsCheckBox_2.Text = 'Display Plots';
            app.RealCellsLabel = uilabel(app.MiniGlandUIFigure);
            app.RealCellsLabel.Text = 'Real Cells';
            app.NHE_alpha_AEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.NHE_alpha_AEditFieldLabel.Text = 'NHE_alpha_A';
            app.NHE_alpha_AEditField = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.NHE_alpha_BEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.NHE_alpha_BEditFieldLabel.Text = 'NHE_alpha_B';
            app.NHE_alpha_BEditField = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.VPLCEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.VPLCEditFieldLabel.Text = 'VPLC';
            app.VPLCEditField = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditFieldLabel = uilabel(app.MiniGlandUIFigure);
            app.parameterEditFieldLabel.Text = 'parameter';
            app.parameterEditField = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_2Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_2Label.Text = 'parameter';
            app.parameterEditField_2 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_3Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_3Label.Text = 'parameter';
            app.parameterEditField_3 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_4Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_4Label.Text = 'parameter';
            app.parameterEditField_4 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_5Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_5Label.Text = 'parameter';
            app.parameterEditField_5 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_6Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_6Label.Text = 'parameter';
            app.parameterEditField_6 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_7Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_7Label.Text = 'parameter';
            app.parameterEditField_7 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_8Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_8Label.Text = 'parameter';
            app.parameterEditField_8 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_9Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_9Label.Text = 'parameter';
            app.parameterEditField_9 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_10Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_10Label.Text = 'parameter';
            app.parameterEditField_10 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_11Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_11Label.Text = 'parameter';
            app.parameterEditField_11 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_12Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_12Label.Text = 'parameter';
            app.parameterEditField_12 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_13Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_13Label.Text = 'parameter';
            app.parameterEditField_13 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_14Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_14Label.Text = 'parameter';
            app.parameterEditField_14 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_15Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_15Label.Text = 'parameter';
            app.parameterEditField_15 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_16Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_16Label.Text = 'parameter';
            app.parameterEditField_16 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_17Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_17Label.Text = 'parameter';
            app.parameterEditField_17 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_18Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_18Label.Text = 'parameter';
            app.parameterEditField_18 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.parameterEditField_19Label = uilabel(app.MiniGlandUIFigure);
            app.parameterEditField_19Label.Text = 'parameter';
            app.parameterEditField_19 = uieditfield(app.MiniGlandUIFigure, 'numeric');
            app.SyntheticCellsLabel = uilabel(app.MiniGlandUIFigure);
            app.SyntheticCellsLabel.Text = 'Synthetic Cells';
            app.DuctSimplifyModelCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.DuctSimplifyModelCheckBox.Text = 'Simplify Model';
            app.C1Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C1Button_2.Text = 'C1';
            app.C2Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.MiniGlandUIFigure, 'state');
            app.C14Button.Text = 'C14';
            app.C1Button_3 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C1Button_3.Text = 'C1';
            app.C2Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C2Button_2.Text = 'C2';
            app.C3Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C3Button_2.Text = 'C3';
            app.C4Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C4Button_2.Text = 'C4';
            app.C5Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C5Button_2.Text = 'C5';
            app.C6Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C6Button_2.Text = 'C6';
            app.C7Button_2 = uibutton(app.MiniGlandUIFigure, 'state');
            app.C7Button_2.Text = 'C7';
            app.ExVivoButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.ExVivoButton.ButtonPushedFcn = createCallbackFcn(app, @ExVivoButtonPushed, true);
            app.ExVivoButton.Text = 'Ex-Vivo';
            app.InVivoButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.InVivoButton.ButtonPushedFcn = createCallbackFcn(app, @InVivoButtonPushed, true);
            app.InVivoButton.Text = 'In-Vivo';
            app.SetDefaultLabel = uilabel(app.MiniGlandUIFigure);
            app.SetDefaultLabel.Text = 'Set Default';
            app.DuctDisplayPlotsCheckBox = uicheckbox(app.MiniGlandUIFigure);
            app.DuctDisplayPlotsCheckBox.Text = 'Display Plots';
            app.DuctDisplayPlotsCheckBox.Value = true;
            app.InputDropDownLabel = uilabel(app.MiniGlandUIFigure);
            app.InputDropDownLabel.Text = 'Input';
            app.DuctInputDropDown = uidropdown(app.MiniGlandUIFigure);
            app.DuctInputDropDown.Items = {'- none -', ''};
            app.DuctInputDropDown.Value = '- none -';
            % Show the figure after all components are created
            app.MiniGlandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_gland_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiniGlandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiniGlandUIFigure)
        end
    end
end